classdef Fitting_plot_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumofbinsEditFieldLabel       matlab.ui.control.Label
        NumofbinsEditField            matlab.ui.control.NumericEditField
        ResultsLabel                  matlab.ui.control.Label
        StartPointEditField           matlab.ui.control.EditField
        StartPointEditFieldLabel      matlab.ui.control.Label
        plot_curve                    matlab.ui.control.Button
        ModelforfittingDropDown       matlab.ui.control.DropDown
        ModelforfittingDropDownLabel  matlab.ui.control.Label
        DataoptionsDropDown           matlab.ui.control.DropDown
        DataoptionsDropDownLabel      matlab.ui.control.Label
        FilepathEditField             matlab.ui.control.EditField
        FilepathEditFieldLabel        matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        raw_data
        x_data
        y_data
        bar_marker
        para
        gof
        coef_list
    end
    
    methods (Access = private)
        
        function my_execute(app)
            num_bins = app.NumofbinsEditField.Value;
            dataOption = app.DataoptionsDropDown.Value;
            fit_model = app.ModelforfittingDropDown.Value;
            startP = app.StartPointEditField.Value;

            switch dataOption
                
                case 'CDF'
                    app.bar_marker = 0;
                    [app.x_data, app.y_data] = cdf(app.raw_data);
                
                case 'CCDF'
                    app.bar_marker = 0;
                    [app.x_data, app.y_data] = ccdf(app.raw_data);
                    
                case 'PDF'
                    if num_bins == 0
                        num_bins = 20;
                    end
                    app.bar_marker = 1;
                    [app.x_data, app.y_data] = pdfCalc(app.raw_data, num_bins);
                    
            end
           
            switch fit_model
                case 'SingleExpo'  %if the input startpoint is empty or the length of it is incerrect, startPoint
                    %edit field will automatically show the default
                    %startpoint number
                    if isempty(startP)
                        startP = [max(app.y_data), mean(app.raw_data)]; %set default startpoint
                        % let the StartPointEditField show default
                        % startpoint value
                        app.StartPointEditField.Value = regexprep(num2str(round(startP,1)), '\s+', ' ');
                    else
                        numericValues = str2double(strsplit(startP, ',')); 
                        startP = numericValues(:).'; % convert string to vector
                        if length(startP) ~= 2
                            startP = [max(app.y_data), mean(app.raw_data)];
                            app.StartPointEditField.Value = regexprep(num2str(round(startP,1)), '\s+', ' ');
                        end
                    end
                    if dataOption == "CDF" 
                        [app.para, app.gof, app.coef_list] = singleExpoFit_up(app.x_data, app.y_data, startP);
                    else             
                        [app.para, app.gof, app.coef_list] = singleExpoFit(app.x_data, app.y_data, startP); 
                        
                    end
                
                case 'Biexponential'
                    if isempty(startP)
                        startP = [2/3*max(app.y_data),1/3*mean(app.raw_data),1/3*max(app.y_data),2/3*mean(app.raw_data)];
                        app.StartPointEditField.Value = regexprep(num2str(round(startP,1)), '\s+', ' ');
                    else
                        numericValues = str2double(strsplit(startP, ',')); 
                        startP = numericValues(:).'; 
                        if length(startP) ~= 4
                            startP = [2/3*max(app.y_data),1/3*mean(app.raw_data),1/3*max(app.y_data),2/3*mean(app.raw_data)];
                            app.StartPointEditField.Value = regexprep(num2str(round(startP,1)), '\s+', ' ');
                        end

                    end
                    if dataOption == "CDF"
                        [app.para, app.gof, app.coef_list] = biExpoFit_up(app.x_data, app.y_data, startP);
                    else 
                       
                        [app.para, app.gof, app.coef_list] = biExpoFit(app.x_data, app.y_data,startP);
                    end
                
                case 'Gaussian'
                    if isempty(startP)
                        startP = [max(app.y_data), mean(app.x_data), std(app.x_data)];
                        app.StartPointEditField.Value = regexprep(num2str(round(startP,1)), '\s+', ' ');
                    else
                        numericValues = str2double(strsplit(startP, ',')); 
                        startP = numericValues(:).';
                        if length(startP) ~= 3
                            startP = [max(app.y_data), mean(app.x_data), std(app.x_data)];
                            app.StartPointEditField.Value = regexprep(num2str(round(startP,1)), '\s+', ' ');
                        end
                    end
                    [app.para, app.gof, app.coef_list] = gaussFit(app.x_data, app.y_data, startP);
                   
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FilepathEditField
        function FilepathEditFieldValueChanged(app, event)
            filepath = app.FilepathEditField.Value;
            app.raw_data = getRawData(filepath);
        end

        % Value changed function: DataoptionsDropDown
        function DataoptionsDropDownValueChanged(app, event)
            
        end

        % Value changed function: ModelforfittingDropDown
        function ModelforfittingDropDownValueChanged(app, event)
            
        end

        % Value changed function: StartPointEditField
        function StartPointEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: NumofbinsEditField
        function NumofbinsEditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: plot_curve
        function plot_curveButtonPushed(app, event)
            my_execute(app);
            plotCurve(app.bar_marker, app.x_data, app.y_data, app.para,app);
            s1 = append('RSquare: ', num2str(app.gof.rsquare));
            s2 = append('RMSE: ', num2str(app.gof.rmse));
            s3 = append('SSE: ', num2str(app.gof.sse));
            s4 = append('Coefficients: ', num2str(app.coef_list));
            %s5 = append('Confi_interval: ', mat2str(round(confint(app.para)', 1)));
            label_str = {s1,s2,s3,s4};
            
            app.ResultsLabel.Text = label_str;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FilepathEditFieldLabel = uilabel(app.UIFigure);
            app.FilepathEditFieldLabel.Text = 'File path';
            app.FilepathEditField = uieditfield(app.UIFigure, 'text');
            app.FilepathEditField.ValueChangedFcn = createCallbackFcn(app, @FilepathEditFieldValueChanged, true);
            app.DataoptionsDropDownLabel = uilabel(app.UIFigure);
            app.DataoptionsDropDownLabel.Text = 'Data options';
            app.DataoptionsDropDown = uidropdown(app.UIFigure);
            app.DataoptionsDropDown.Items = {'CDF', 'CCDF', 'PDF'};
            app.DataoptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @DataoptionsDropDownValueChanged, true);
            app.DataoptionsDropDown.Value = 'CDF';
            app.ModelforfittingDropDownLabel = uilabel(app.UIFigure);
            app.ModelforfittingDropDownLabel.Text = 'Model for fitting';
            app.ModelforfittingDropDown = uidropdown(app.UIFigure);
            app.ModelforfittingDropDown.Items = {'SingleExpo', 'Biexponential', 'Gaussian'};
            app.ModelforfittingDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelforfittingDropDownValueChanged, true);
            app.ModelforfittingDropDown.Value = 'SingleExpo';
            app.plot_curve = uibutton(app.UIFigure, 'push');
            app.plot_curve.ButtonPushedFcn = createCallbackFcn(app, @plot_curveButtonPushed, true);
            app.plot_curve.Text = 'Plot';
            app.StartPointEditFieldLabel = uilabel(app.UIFigure);
            app.StartPointEditFieldLabel.Text = 'StartPoint';
            app.StartPointEditField = uieditfield(app.UIFigure, 'text');
            app.StartPointEditField.ValueChangedFcn = createCallbackFcn(app, @StartPointEditFieldValueChanged, true);
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.NumofbinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumofbinsEditField.ValueChangedFcn = createCallbackFcn(app, @NumofbinsEditFieldValueChanged, true);
            app.NumofbinsEditFieldLabel = uilabel(app.UIFigure);
            app.NumofbinsEditFieldLabel.Text = 'Num of bins';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fitting_plot_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end